classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        SaveUserPreferences            matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        CellSelectionTab               matlab.ui.container.Tab
        InputDataFolderEditFieldLabel  matlab.ui.control.Label
        InputDataFolderEditField       matlab.ui.control.EditField
        BrowseInputButton              matlab.ui.control.Button
        EditFieldLabel                 matlab.ui.control.Label
        OutputDataFolderEditField      matlab.ui.control.EditField
        BrowseOutputButton             matlab.ui.control.Button
        FoldersLabel                   matlab.ui.control.Label
        FolderListBox                  matlab.ui.control.ListBox
        ButtonSubmitFolders            matlab.ui.control.Button
        TableInputFolder               matlab.ui.control.Table
        IWF_Logo_1                     matlab.ui.control.UIAxes
        ImageImportTab                 matlab.ui.container.Tab
        IWF_Logo_2                     matlab.ui.control.UIAxes
        ConfigurationforpicturestakeninXDirectionPanel  matlab.ui.container.Panel
        MinimalelectrodelengthSliderLabel  matlab.ui.control.Label
        MinimalelectrodelengthSlider   matlab.ui.control.Slider
        DistancefrommiddlepictureSliderLabel  matlab.ui.control.Label
        DistancefrommiddlepictureSlider  matlab.ui.control.Slider
        ConfigurationforpicturestakeninYDirectionPanel  matlab.ui.container.Panel
        MInmalelectrodelengthSliderLabel  matlab.ui.control.Label
        MInmalelectrodelengthSlider    matlab.ui.control.Slider
        MinimaldistancetoedgeSliderLabel  matlab.ui.control.Label
        MinimaldistancetoedgeSlider    matlab.ui.control.Slider
        ConfigurationforpicturestakeninZDirectionPanel  matlab.ui.container.Panel
        NumerofPicturesSliderLabel     matlab.ui.control.Label
        NumerofPicturesSlider          matlab.ui.control.Slider
        ShowHelperPreview              matlab.ui.control.UIAxes
        StartImportButton              matlab.ui.control.Button
    end


    properties (Access = public)
        
        FolderSelection = struct; % Description
        ImageSelection = struct;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Test_Mode = true;
            appstart(app,Test_Mode);
            %imshow('IWF Logo.png', 'Parent', app.IWF_Logo_1);
            
        end

        % Button pushed function: BrowseInputButton
        function BrowseInputButtonPushed(app, event)
           FolderPicker(app,"input");
        end

        % Button pushed function: BrowseOutputButton
        function BrowseOutputButtonPushed(app, event)
           FolderPicker(app,"output");
        end

        % Button pushed function: ButtonSubmitFolders
        function ButtonSubmitFoldersPushed(app, event)
            app.FolderSelection.InputFolders.Selected_Values = app.FolderListBox.Value;
            app.TableInputFolder.Data = CreateTable(app,"Load");
        end

        % Value changing function: MinimalelectrodelengthSlider
        function MinimalelectrodelengthSliderValueChanging(app, event)
            imshow('AppImages/xMinLength.png', 'Parent', app.ShowHelperPreview);
            
        end

        % Value changing function: DistancefrommiddlepictureSlider
        function DistancefrommiddlepictureSliderValueChanging(app, event)
            imshow('AppImages/xDistToMiddle.png', 'Parent', app.ShowHelperPreview);
            
        end

        % Value changing function: MInmalelectrodelengthSlider
        function MInmalelectrodelengthSliderValueChanging(app, event)
            imshow('AppImages/yMinLength.png', 'Parent', app.ShowHelperPreview);
            
        end

        % Value changing function: MinimaldistancetoedgeSlider
        function MinimaldistancetoedgeSliderValueChanging(app, event)
            imshow('AppImages/yDistToEdge.png', 'Parent', app.ShowHelperPreview);
            
        end

        % Button pushed function: StartImportButton
        function StartImportButtonPushed(app, event)
            images_to_process_new(app);
            
        end

        % Menu selected function: SaveUserPreferences
        function SaveUserPreferencesMenuSelected(app, event)
            CreateUserPref(app);
        end

        % Value changed function: MinimalelectrodelengthSlider
        function MinimalelectrodelengthSliderValueChanged(app, event)
            app.ImageSelection.ImportSettings.xMinLength = ceil(app.MinimalelectrodelengthSlider.Value);     
        end

        % Value changed function: DistancefrommiddlepictureSlider
        function DistancefrommiddlepictureSliderValueChanged(app, event)
            app.ImageSelection.ImportSettings.xDistToMiddle =  ceil(app.DistancefrommiddlepictureSlider.Value);
        end

        % Value changed function: MInmalelectrodelengthSlider
        function MInmalelectrodelengthSliderValueChanged(app, event)
            app.ImageSelection.ImportSettings.yMinLength =   ceil(app.MInmalelectrodelengthSlider.Value);
        end

        % Value changed function: MinimaldistancetoedgeSlider
        function MinimaldistancetoedgeSliderValueChanged(app, event)
            app.ImageSelection.ImportSettings.yDistToEdge =  ceil(app.MinimaldistancetoedgeSlider.Value);
        end

        % Value changed function: NumerofPicturesSlider
        function NumerofPicturesSliderValueChanged(app, event)
            app.ImageSelection.ImportSettings.zAmount =  ceil(app.NumerofPicturesSlider.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SaveUserPreferences = uimenu(app.Menu);
            app.SaveUserPreferences.MenuSelectedFcn = createCallbackFcn(app, @SaveUserPreferencesMenuSelected, true);
            app.SaveUserPreferences.Text = 'Save User Preferences';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CellSelectionTab = uitab(app.TabGroup);
            app.CellSelectionTab.Title = 'Cell Selection';
            app.InputDataFolderEditFieldLabel = uilabel(app.CellSelectionTab);
            app.InputDataFolderEditFieldLabel.Text = 'Input Data Folder';
            app.InputDataFolderEditField = uieditfield(app.CellSelectionTab, 'text');
            app.BrowseInputButton = uibutton(app.CellSelectionTab, 'push');
            app.BrowseInputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseInputButtonPushed, true);
            app.BrowseInputButton.Text = 'Browse';
            app.EditFieldLabel = uilabel(app.CellSelectionTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.OutputDataFolderEditField = uieditfield(app.CellSelectionTab, 'text');
            app.BrowseOutputButton = uibutton(app.CellSelectionTab, 'push');
            app.BrowseOutputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseOutputButtonPushed, true);
            app.BrowseOutputButton.Text = 'Browse';
            app.FoldersLabel = uilabel(app.CellSelectionTab);
            app.FoldersLabel.Text = 'Folders';
            app.FolderListBox = uilistbox(app.CellSelectionTab);
            app.FolderListBox.Items = {};
            app.FolderListBox.Multiselect = 'on';
            app.FolderListBox.Value = {};
            app.ButtonSubmitFolders = uibutton(app.CellSelectionTab, 'push');
            app.ButtonSubmitFolders.ButtonPushedFcn = createCallbackFcn(app, @ButtonSubmitFoldersPushed, true);
            app.ButtonSubmitFolders.Text = 'Submit';
            app.TableInputFolder = uitable(app.CellSelectionTab);
            app.TableInputFolder.ColumnName = '';
            app.TableInputFolder.RowName = {};
            app.IWF_Logo_1 = uiaxes(app.CellSelectionTab);
            app.ImageImportTab = uitab(app.TabGroup);
            app.ImageImportTab.Title = 'Image Import';
            app.IWF_Logo_2 = uiaxes(app.ImageImportTab);
            app.ConfigurationforpicturestakeninXDirectionPanel = uipanel(app.ImageImportTab);
            app.ConfigurationforpicturestakeninXDirectionPanel.Title = 'Configuration for pictures taken in X-Direction';
            app.MinimalelectrodelengthSliderLabel = uilabel(app.ConfigurationforpicturestakeninXDirectionPanel);
            app.MinimalelectrodelengthSliderLabel.Text = 'Minimal electrode length';
            app.MinimalelectrodelengthSlider = uislider(app.ConfigurationforpicturestakeninXDirectionPanel);
            app.MinimalelectrodelengthSlider.Limits = [600 800];
            app.MinimalelectrodelengthSlider.ValueChangedFcn = createCallbackFcn(app, @MinimalelectrodelengthSliderValueChanged, true);
            app.MinimalelectrodelengthSlider.ValueChangingFcn = createCallbackFcn(app, @MinimalelectrodelengthSliderValueChanging, true);
            app.MinimalelectrodelengthSlider.Value = 680;
            app.DistancefrommiddlepictureSliderLabel = uilabel(app.ConfigurationforpicturestakeninXDirectionPanel);
            app.DistancefrommiddlepictureSliderLabel.Text = 'Distance from middle picture';
            app.DistancefrommiddlepictureSlider = uislider(app.ConfigurationforpicturestakeninXDirectionPanel);
            app.DistancefrommiddlepictureSlider.Limits = [20 120];
            app.DistancefrommiddlepictureSlider.MajorTicks = [20 30 40 50 60 70 80 90 100 110 120];
            app.DistancefrommiddlepictureSlider.ValueChangedFcn = createCallbackFcn(app, @DistancefrommiddlepictureSliderValueChanged, true);
            app.DistancefrommiddlepictureSlider.ValueChangingFcn = createCallbackFcn(app, @DistancefrommiddlepictureSliderValueChanging, true);
            app.DistancefrommiddlepictureSlider.Value = 100;
            app.ConfigurationforpicturestakeninYDirectionPanel = uipanel(app.ImageImportTab);
            app.ConfigurationforpicturestakeninYDirectionPanel.Title = 'Configuration for pictures taken in Y-Direction';
            app.MInmalelectrodelengthSliderLabel = uilabel(app.ConfigurationforpicturestakeninYDirectionPanel);
            app.MInmalelectrodelengthSliderLabel.Text = 'MInmal electrode length';
            app.MInmalelectrodelengthSlider = uislider(app.ConfigurationforpicturestakeninYDirectionPanel);
            app.MInmalelectrodelengthSlider.Limits = [400 600];
            app.MInmalelectrodelengthSlider.ValueChangedFcn = createCallbackFcn(app, @MInmalelectrodelengthSliderValueChanged, true);
            app.MInmalelectrodelengthSlider.ValueChangingFcn = createCallbackFcn(app, @MInmalelectrodelengthSliderValueChanging, true);
            app.MInmalelectrodelengthSlider.Value = 500;
            app.MinimaldistancetoedgeSliderLabel = uilabel(app.ConfigurationforpicturestakeninYDirectionPanel);
            app.MinimaldistancetoedgeSliderLabel.Text = 'Minimal distance to edge';
            app.MinimaldistancetoedgeSlider = uislider(app.ConfigurationforpicturestakeninYDirectionPanel);
            app.MinimaldistancetoedgeSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.MinimaldistancetoedgeSlider.ValueChangedFcn = createCallbackFcn(app, @MinimaldistancetoedgeSliderValueChanged, true);
            app.MinimaldistancetoedgeSlider.ValueChangingFcn = createCallbackFcn(app, @MinimaldistancetoedgeSliderValueChanging, true);
            app.MinimaldistancetoedgeSlider.Value = 20;
            app.ConfigurationforpicturestakeninZDirectionPanel = uipanel(app.ImageImportTab);
            app.ConfigurationforpicturestakeninZDirectionPanel.Title = 'Configuration for pictures taken in Z-Direction';
            app.NumerofPicturesSliderLabel = uilabel(app.ConfigurationforpicturestakeninZDirectionPanel);
            app.NumerofPicturesSliderLabel.Text = 'Numer of Pictures';
            app.NumerofPicturesSlider = uislider(app.ConfigurationforpicturestakeninZDirectionPanel);
            app.NumerofPicturesSlider.Limits = [10 50];
            app.NumerofPicturesSlider.MajorTicks = [10 15 20 25 30 35 40 45 50];
            app.NumerofPicturesSlider.ValueChangedFcn = createCallbackFcn(app, @NumerofPicturesSliderValueChanged, true);
            app.NumerofPicturesSlider.Value = 30;
            app.ShowHelperPreview = uiaxes(app.ImageImportTab);
            app.StartImportButton = uibutton(app.ImageImportTab, 'push');
            app.StartImportButton.ButtonPushedFcn = createCallbackFcn(app, @StartImportButtonPushed, true);
            app.StartImportButton.Text = 'Start Import';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end